classdef defocustracker_ui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        LeftPanel                    matlab.ui.container.Panel
        LogTextArea                  matlab.ui.control.TextArea
        LogTextAreaLabel             matlab.ui.control.Label
        FoundZDataEditField          matlab.ui.control.EditField
        FoundZDataEditFieldLabel     matlab.ui.control.Label
        DemoModeCheckBox             matlab.ui.control.CheckBox
        MedianFilterCheckBox         matlab.ui.control.CheckBox
        ProcessButton                matlab.ui.control.Button
        ZstackMaxEditField           matlab.ui.control.NumericEditField
        ZstackMaxEditFieldLabel      matlab.ui.control.Label
        Z_stackminEditField          matlab.ui.control.NumericEditField
        Z_stackminEditFieldLabel     matlab.ui.control.Label
        FoundExperimtalDataTextArea  matlab.ui.control.TextArea
        FoundExperimtalDataTextAreaLabel  matlab.ui.control.Label
        CurrentFrameEditField        matlab.ui.control.NumericEditField
        CurrentFrameEditFieldLabel   matlab.ui.control.Label
        Slider                       matlab.ui.control.Slider
        SliderLabel                  matlab.ui.control.Label
        TrainButton                  matlab.ui.control.Button
        LoadDataButton               matlab.ui.control.Button
        LoadZstackDataButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        zstack_fullpath         cell %cell array with full path to zstack images
        zstack_folder           char %cell array with folder path to zstack images
        zstack_files            cell %cell array with just filenames to zstack images
        experiment_folders      cell %cell array with just filenames to zstack images
        experiment_base_folder  char
        save_filename           cell
        roi     
        roi_pos %property used to recreate the roi, ROI obj gets deleted when a new images is drawn using imshow
        fig_zstack
        fig_zstack_zoom
        fig_train
        fig_model
        ax_roi
        ax_im
        ax_crop
        im
        model
        d_helper
        debugmode
        logger
        app_dir                     % the directory where mlapp is located
    end
    
    methods (Access = private)       
        function [] = update_image(app, idx)
            I = imread(app.zstack_fullpath{idx});
            I= imadjust(I);
            app.im=I;
            imshow(I,"Parent", app.ax_im);
            update_roi(app)                              
            update_cropped_image(app)                            
        end

        function [] = update_cropped_image(app)
            position=app.roi.Position;
            I2=imcrop(app.im,position);             
            imshow(I2,"Parent", app.ax_crop);  
        end

        
        function [] = update_roi(app)
            app.roi=images.roi.Rectangle(app.ax_im,'Position',app.roi_pos);
            addlistener(app.roi,'MovingROI',@app.roi_listener);
            addlistener(app.roi,'ROIMoved',@app.roi_listener);
            
        end
        
        function [] = roi_listener(app,src,evt)
            evname = evt.EventName;
                 switch(evname)
%                     case{'MovingROI'}
%                         disp(['ROI moving Previous Position: ' mat2str(evt.PreviousPosition)]);
%                         disp(['ROI moving Current Position: ' mat2str(evt.CurrentPosition)]);
                    case{'ROIMoved'}
                        %disp(['ROI moved Previous Position: ' mat2str(evt.PreviousPosition)]);
                        %disp(['ROI moved Current Position: ' mat2str(evt.CurrentPosition)]);
                        app.roi_pos=evt.CurrentPosition;
                        update_cropped_image(app);
                 end            
        end


        function [] = train(app) %ouputs data to matlab workspace
            images=app.zstack_files;
            myfolder=app.zstack_folder;
            first_frame=round(app.Z_stackminEditField.Value);
            last_frame=round(app.ZstackMaxEditField .Value);
            images=images(first_frame:last_frame);
            img_train = dtracker_create('imageset',myfolder, images);
            img_train.n_frames=numel(images);
            position=app.roi.Position;
            N_cal = img_train.n_frames;
            dat_train = dtracker_create('dataset',N_cal);
            dat_train.fr(:) = 1:N_cal;
            dat_train.X(:) = zeros(1,N_cal) + position(1)+position(3)/2; %350 is the X-coord. of the selected particle
            dat_train.Y(:) = zeros(1,N_cal) + position(2)+position(4)/2; %855 is the Y-coord. of the selected particle
            dat_train.Z(:) = linspace(0,1,N_cal);
            
            %  Create the model with default parameters
            model_wte2 = dtracker_create('model');       
            %  Edit the relevant field in training parameters
            model_wte2.training.imwidth = position(3);            % width of the particle image
            model_wte2.training.imheight =  position(4);           % height of the particle image
            model_wte2.training.median_filter = 3;       % median filter to reduce thermal noise
            model_wte2.training.smoothing = 0.2;         % smoothing (optional)
            app.model=model_wte2;
            %model_wte2.training.n_interp_images = 100;   % interpolation (optional)
            %  Train the *model*
            model_wte2 = dtracker_train(model_wte2,img_train,dat_train); 
            app.model=model_wte2;
            %  Inspect the *model*
            disp("empty figure Ola")
            app.fig_model
            disp('sihandle')
            ishandle(app.fig_model)
            disp('siempty')
            isempty(app.fig_model)            
            app.close_dtrackerfigure() %closes previously open dtracker igures
            dtracker_show(img_train,dat_train)
            dtracker_show(model_wte2)
            %assignin("base", "model", model_wte2)
        end
        
        function  [] = process(app)            
            paths=app.experiment_folders;
            data={};
            img_exp={};
            for i=1 :numel(paths)                
                if app.MedianFilterCheckBox.Value==1
                    median_filtered_images=app.d_helper.median(paths{i});
                    img_exp = dtracker_create('preloaded_imageset',median_filtered_images);
                else                
                    img_exp = dtracker_create('imageset',paths{i});                
                end
               
                %  Run the processing on all data frames %%might be
                %  unnecceasey
                app.add_to_log(['Processing ' num2str(i) 'of ' num2str(numel(paths)) ' experiments']);
                drawnow;
                model=app.model;
                %  Add the tracking
                tracking_wte2 = dtracker_create('tracking');
                tracking_wte2.tracking_step = 1;
                tracking_wte2.bounding_box = [-100 100 -100 100 -10 10]; %  [DXmin DXmax DYmin DYmax DZmin DZmax]
                model.tracking = tracking_wte2;
                dat_exp = dtracker_process(model, img_exp);                            
                data{end+1}=dat_exp;                
            end
            assignin("base", "model", model)
            assignin("base", "data", data)
            assignin("base", "paths", paths)            
            app.add_to_log(['Processing complete (' num2str(numel(paths)) ' experiments)']);
            wheretosave=fullfile(app.experiment_base_folder, app.save_filename{1});
            save(wheretosave,"model", "data", "paths");
        end
        
        function [] = close_dtrackerfigure(app) % this function closes some figures created by dtracker. I did not want to refactor dtracker to much
            h=findobj('Type','figure');
            for i=1: numel(h)
                if strcmp(h(i).Name,'dataset: dat_train / imageset: img_train')
                    close(h(i))                
                elseif strcmp(h(i).Name,'model: model_wte2')
                    close(h(i))
                end                    
            end
        end
        
        function [] = add_to_log(app,to_add)
            to_add= [datestr(datetime) '    ' to_add];            
            app.logger=[{to_add} app.logger];
            app.LogTextArea.Value=app.logger;            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all
            [filepath,~,~] = fileparts(mfilename('fullpath'));
            app.app_dir=filepath;
            addpath(genpath(filepath))
            app.fig_zstack=figure("Name", "full image");
            app.fig_zstack_zoom=figure("Name", "cropped image");
            app.ax_im=axes('Parent',app.fig_zstack,'Units','normalized');
            app.ax_crop=axes('Parent',app.fig_zstack_zoom,'Units','normalized');
            app.Z_stackminEditField.Value=30;
            app.ZstackMaxEditField .Value=100;
            app.d_helper= defocustracker_helperfunctions;
            %app.ax_roi=axes('Parent',app.fig,'Units','normalized');
            app.debugmode=0;
            app.logger={};
            app.add_to_log('program started')
        end

        % Button pushed function: LoadZstackDataButton
        function LoadZstackDataButtonPushed(app, event)
            if app.debugmode==1
            myfolder=fullfile(app.app_dir,'demo_data\zstack');
            else
            myfolder = uigetdir;
            end
            
            %myfolder = uigetdir;
            if myfolder~=0
                z_stack_imgs=dir(fullfile(myfolder, '**\*.tif'));
                folder={z_stack_imgs.folder};
                filenames={z_stack_imgs.name};
                app.zstack_fullpath=fullfile(folder, filenames);
                fold=folder(1);
                app.zstack_folder=fold{1};
                app.zstack_files=(filenames);
                app.roi_pos=[80,45,80,100];
                update_image(app,1)
                app.Slider.Limits=[1 numel(filenames) ];
                %app.roi = images.roi.Rectangle(app.ax_roi,'Position',[80,45,80,100]);
                %app.roi = drawrectangle('Position',[500,500,1000,1000],'StripeColor','r');
                app.TrainButton.Enable="on";
                app.Slider.Enable="on";
                app.FoundZDataEditField.Value=fold{1};
                app.add_to_log('zstack loaded')

            end

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = round(app.Slider.Value);
            app.Slider.Value=value;
            update_image(app,value);
            app.CurrentFrameEditField.Value=value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                close(app.fig_zstack)
            end
            try
                close(app.fig_zstack_zoom)
            end
            delete(app)
            
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            %myfolder='C:\test\230131\zstack';
            if app.debugmode==1
                myfolder=fullfile(app.app_dir,'demo_data\experiments');
            else
            myfolder = uigetdir;
            end
            if myfolder~=0
                data_imgs=dir(fullfile(myfolder, '**\*.tif'));
                folder={data_imgs.folder};
                unique_folder=unique(folder);           
                app.experiment_folders=unique_folder;
                app.FoundExperimtalDataTextArea.Value=unique_folder;
                app.ProcessButton.Enable="on";
            end
            app.experiment_base_folder=myfolder;
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            app.train();
            app.add_to_log('model trained')
            app.LoadDataButton.Enable="on";
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            prompt = {'Enter name of .mat file where data is saved:'};
            dlgtitle = 'filename. pls put.mat as file ending:';
            dims = [1 50];
            definput = {'data.mat',};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            app.save_filename=answer;
            if ~isempty(answer)
                app.process()
            end
        end

        % Value changed function: DemoModeCheckBox
        function DemoModeCheckBoxValueChanged(app, event)
            value = app.DemoModeCheckBox.Value;
            app.debugmode=value;
        end

        % Callback function
        function addtologButtonPushed(app, event)
            app.add_to_log('hello')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LeftPanel = uipanel(app.UIFigure);
            app.LoadZstackDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadZstackDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadZstackDataButtonPushed, true);
            app.LoadZstackDataButton.Text = 'Load Z stack Data';
            app.LoadDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Enable = 'off';
            app.LoadDataButton.Text = 'Load Data';
            app.TrainButton = uibutton(app.LeftPanel, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Enable = 'off';
            app.TrainButton.Text = 'Train';
            app.SliderLabel = uilabel(app.LeftPanel);
            app.SliderLabel.Enable = 'off';
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.LeftPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.Slider.Enable = 'off';
            app.CurrentFrameEditFieldLabel = uilabel(app.LeftPanel);
            app.CurrentFrameEditFieldLabel.Text = 'CurrentFrame';
            app.CurrentFrameEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FoundExperimtalDataTextAreaLabel = uilabel(app.LeftPanel);
            app.FoundExperimtalDataTextAreaLabel.Text = 'Found Experimtal Data';
            app.FoundExperimtalDataTextArea = uitextarea(app.LeftPanel);
            app.Z_stackminEditFieldLabel = uilabel(app.LeftPanel);
            app.Z_stackminEditFieldLabel.Text = 'Z_stack min';
            app.Z_stackminEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ZstackMaxEditFieldLabel = uilabel(app.LeftPanel);
            app.ZstackMaxEditFieldLabel.Text = 'Zstack Max';
            app.ZstackMaxEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ProcessButton = uibutton(app.LeftPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Enable = 'off';
            app.ProcessButton.Text = 'Process';
            app.MedianFilterCheckBox = uicheckbox(app.LeftPanel);
            app.MedianFilterCheckBox.Tooltip = {'Removes "stuck particles" in the experimental data frames'};
            app.MedianFilterCheckBox.Text = 'Median Filter';
            app.DemoModeCheckBox = uicheckbox(app.LeftPanel);
            app.DemoModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @DemoModeCheckBoxValueChanged, true);
            app.DemoModeCheckBox.Text = 'DemoMode';
            app.FoundZDataEditFieldLabel = uilabel(app.LeftPanel);
            app.FoundZDataEditFieldLabel.Text = 'Found Z Data';
            app.FoundZDataEditField = uieditfield(app.LeftPanel, 'text');
            app.LogTextAreaLabel = uilabel(app.LeftPanel);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.LeftPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = defocustracker_ui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end